classdef sensor_project_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImportadctxtButton             matlab.ui.control.Button
        UIAxes_adc_signal              matlab.ui.control.UIAxes
        UIAxes_spectrogram             matlab.ui.control.UIAxes
        loadCNNpredictButton           matlab.ui.control.Button
        GabortransformButton           matlab.ui.control.Button
        emptyLampLabel                 matlab.ui.control.Label
        emptyLamp                      matlab.ui.control.Lamp
        humanLampLabel                 matlab.ui.control.Label
        humanLamp                      matlab.ui.control.Lamp
        Step1ImportechosignalintotheappLabel  matlab.ui.control.Label
        Step1ImportechosignalintotheappLabel_2  matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        Step3LoadCNNpretrainedmodelandpredicttheresultLabel  matlab.ui.control.Label
        spectrogramavailableLampLabel  matlab.ui.control.Label
        spectrogramavailableLamp       matlab.ui.control.Lamp
    end

    properties (Access = private)
        index
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportadctxtButton
        function ImportadctxtButtonPushed(app, event)
            A = readmatrix("adc.txt");
            B = A(:,17:length(A));
            i = randi(size(B,1));
            app.index = i;
            
            S = B(i,:);
            S = S./max(max(S),abs(min(S)));
            S = S-mean(S);
            t = 1:length(S);
            plot(app.UIAxes_adc_signal,t,S);
            app.UIAxes_adc_signal.Title.String = "echo sample "+i;
            
            app.emptyLamp.Visible = 'off';
            app.humanLamp.Visible = 'off';
            delete spectrogram.png
            app.spectrogramavailableLamp.Visible = 'off';
            cla(app.UIAxes_spectrogram);
        end

        % Callback function
        function clearallplotsButtonPushed(app, event)
            cla(app.UIAxes_adc_signal);
            cla(app.UIAxes_spectrogram);
            app.emptyLamp.Visible = 'off';
            app.humanLamp.Visible = 'off';
        end

        % Button pushed function: GabortransformButton
        function GabortransformButtonPushed(app, event)
            A = readmatrix("adc.txt");
            B = A(:,17:length(A));
            S = B(app.index,:);
            S = S./max(max(S),abs(min(S)));
            S = S-mean(S);
            t = 1:length(S);
            
            L = 40;
            n = length(S);
            k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k);
            Sgt_spec = [];
            tslide = 0:100:n;
            for j = 1:length(tslide)
                g = exp(-5*1e-5*(t-tslide(j)).^2);
                Sg = g.*S;
                Sgt = fft(Sg);
                Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
            end
            Sgt_spec=Sgt_spec';
           
            app.UIAxes_spectrogram.Visible = 'off';
            pcolor(app.UIAxes_spectrogram,tslide,ks,Sgt_spec);
            shading(app.UIAxes_spectrogram,"interp");
            colormap(app.UIAxes_spectrogram,"hot");
            ylim(app.UIAxes_spectrogram,[40 70]);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            colormap(app.UIAxes_spectrogram,"gray");
            exportgraphics(app.UIAxes_spectrogram,"spectrogram.png","Resolution",100);
            image = imread("spectrogram.png");
            image = rgb2gray(image);
            image = imresize(image,[361 456]);
            imwrite(image,"spectrogram.png");
            
            app.spectrogramavailableLamp.Visible = 'on';
            app.spectrogramavailableLamp.Color = 'green';
        end

        % Button pushed function: loadCNNpredictButton
        function loadCNNpredictButtonPushed(app, event)
            load CNNnet.mat;          
            imds = imageDatastore("spectrogram.png");
            prediction = classify(net,imds);
            
            if prediction == "empty"
                app.emptyLamp.Visible = 'on';
                app.emptyLamp.Color = 'red';
                app.humanLamp.Visible = 'off';

            end
            if prediction == "human"
                app.humanLamp.Visible = 'on';
                app.humanLamp.Color = 'red';
                app.emptyLamp.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportadctxtButton = uibutton(app.UIFigure, 'push');
            app.ImportadctxtButton.ButtonPushedFcn = createCallbackFcn(app, @ImportadctxtButtonPushed, true);
            app.ImportadctxtButton.Text = 'Import adc.txt';
            app.UIAxes_adc_signal = uiaxes(app.UIFigure);
            title(app.UIAxes_adc_signal, 'adc signal')
            xlabel(app.UIAxes_adc_signal, {'time'; ''})
            ylabel(app.UIAxes_adc_signal, 'magnitude')
            app.UIAxes_adc_signal.XLim = [0 16400];
            app.UIAxes_adc_signal.YLim = [-1 1];
            app.UIAxes_spectrogram = uiaxes(app.UIFigure);
            title(app.UIAxes_spectrogram, 'spectrogram')
            xlabel(app.UIAxes_spectrogram, 'time')
            ylabel(app.UIAxes_spectrogram, 'frequency')
            app.UIAxes_spectrogram.XLim = [0 16400];
            app.loadCNNpredictButton = uibutton(app.UIFigure, 'push');
            app.loadCNNpredictButton.ButtonPushedFcn = createCallbackFcn(app, @loadCNNpredictButtonPushed, true);
            app.loadCNNpredictButton.Text = 'load CNN & predict';
            app.GabortransformButton = uibutton(app.UIFigure, 'push');
            app.GabortransformButton.ButtonPushedFcn = createCallbackFcn(app, @GabortransformButtonPushed, true);
            app.GabortransformButton.Text = {'Gabor transform'; ''};
            app.emptyLampLabel = uilabel(app.UIFigure);
            app.emptyLampLabel.Text = 'empty';
            app.emptyLamp = uilamp(app.UIFigure);
            app.humanLampLabel = uilabel(app.UIFigure);
            app.humanLampLabel.Text = 'human';
            app.humanLamp = uilamp(app.UIFigure);
            app.Step1ImportechosignalintotheappLabel = uilabel(app.UIFigure);
            app.Step1ImportechosignalintotheappLabel.Text = 'Step 1: Import echo signal file into the app, a random sample will be picked';
            app.Step1ImportechosignalintotheappLabel_2 = uilabel(app.UIFigure);
            app.Step1ImportechosignalintotheappLabel_2.Text = 'Step 2: Export the spectrogram';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Step3LoadCNNpretrainedmodelandpredicttheresultLabel = uilabel(app.UIFigure);
            app.Step3LoadCNNpretrainedmodelandpredicttheresultLabel.Text = 'Step 3: Load CNN pre-trained model and predict the result';
            app.spectrogramavailableLampLabel = uilabel(app.UIFigure);
            app.spectrogramavailableLampLabel.Text = 'spectrogram available';
            app.spectrogramavailableLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensor_project_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end